classdef audioBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        NavigationMiniMicPanel     matlab.ui.container.Panel
        miniNavigation             matlab.ui.container.GridLayout
        miniBack                   matlab.ui.control.Button
        miniForward                matlab.ui.control.Button
        miniStartLabel             matlab.ui.control.Label
        miniStart                  matlab.ui.control.NumericEditField
        StepSizeSpinnerLabel       matlab.ui.control.Label
        miniStepSizeSpinner        matlab.ui.control.Spinner
        miniRefreshPlot            matlab.ui.control.Button
        miniFileName               matlab.ui.control.Label
        miniLoadFileButton         matlab.ui.control.Button
        miniPrevButton             matlab.ui.control.Button
        miniNextButton             matlab.ui.control.Button
        miniEndLabel               matlab.ui.control.Label
        miniEnd                    matlab.ui.control.NumericEditField
        miniSetTriggerButton       matlab.ui.control.Button
        miniTriggerTimeEditField   matlab.ui.control.NumericEditField
        NavigationMainMicPanel     matlab.ui.container.Panel
        mainNavigation             matlab.ui.container.GridLayout
        mainBack                   matlab.ui.control.Button
        mainForward                matlab.ui.control.Button
        mainStartLabel             matlab.ui.control.Label
        mainEndLabel               matlab.ui.control.Label
        mainStart                  matlab.ui.control.NumericEditField
        mainEnd                    matlab.ui.control.NumericEditField
        StepSizeSpinner_2Label     matlab.ui.control.Label
        mainStepSizeSpinner        matlab.ui.control.Spinner
        mainRefreshPlot            matlab.ui.control.Button
        mainFileName               matlab.ui.control.Label
        mainLoadFileButton         matlab.ui.control.Button
        mainNextButton             matlab.ui.control.Button
        mainPrevButton             matlab.ui.control.Button
        mainTriggerTimeEditField   matlab.ui.control.NumericEditField
        mainSetTriggerButton       matlab.ui.control.Button
        LinkNavigationStateButton  matlab.ui.control.StateButton
        CenterPanel                matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        mainSpectAx                matlab.ui.control.UIAxes
        mainPSDAx                  matlab.ui.control.UIAxes
        miniSpectAx                matlab.ui.control.UIAxes
        miniPSDAx                  matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        rightGrid                  matlab.ui.container.GridLayout
        LinkSelectionStateButton   matlab.ui.control.StateButton
        ClearROIsButton            matlab.ui.control.Button
        UpdateSelectionButton      matlab.ui.control.Button
        ReloadROIButton            matlab.ui.control.Button
        SelectButton               matlab.ui.control.StateButton
        ExportSaveButton           matlab.ui.control.Button
        ROItypesListBoxLabel       matlab.ui.control.Label
        ROItypesListBox            matlab.ui.control.ListBox
        SelectAxesButtonGroup      matlab.ui.container.ButtonGroup
        selectMainSpect            matlab.ui.control.RadioButton
        selectMainPSD              matlab.ui.control.RadioButton
        selectMiniSpect            matlab.ui.control.RadioButton
        selectMiniPSD              matlab.ui.control.RadioButton
        EditButton                 matlab.ui.control.Button
        AutoDetectionButton        matlab.ui.control.Button
        OverlaySniffingButton      matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        ContextMenu                matlab.ui.container.ContextMenu
        EditMenu                   matlab.ui.container.Menu
        ContextMenu2               matlab.ui.container.ContextMenu
        ChangejumpRangeMenu        matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        % Instanses of audioClip class
        
        % Range to display on axes
        mainTimes = [0 1];
        miniTimes = [0 1];
        %Set the current ax for selection
        currentAx
        ConnectedAxes
        allAxes
        %Roi list
        roiList = cell(1,4);
        selectedRoiType = [];
        roiTypeList
        
        detectionNetwork = [];
        definedFilter = []; % Description
        mainTriggerTime = struct('Value',0);
        miniTriggerTime = struct('Value',0);
        
        overlayAppHandle = [];
        jumpRange = 1; %Range of syllable surrounding to display when jumping to next syllable
    end
    
    properties (Access = public)
        mainData = audioClip();
        miniData = audioClip();
        
        mainVecSource = 'file' ; %if the file was loaded from workspace, you must save the vector with the file
        miniVecSource =  'file' ;
        
    end
    
    methods (Access = private)
        
        function updatePlots(app,choice)
            %Check validiti of range
            maxLen = 200;
            tempTimes = app.mainTimes;
            
            if ~(diff(tempTimes)<0)
                app.mainData.times_ = tempTimes;
            else
                tempTimes = app.mainData.times;
                app.mainTimes = tempTimes;
                app.mainStart.Value = tempTimes(1);
                app.mainEnd.Value = tempTimes(2);
            end
            
            %Check validiti of range
            tempTimes = app.miniTimes;
            if ~(diff(tempTimes)<0)
                app.miniData.times_ = tempTimes;
            else
                tempTimes = app.miniData.times;
                app.miniTimes = tempTimes;
                app.miniStart.Value = tempTimes(1);
                app.miniEnd.Value = tempTimes(2);
            end
            
            
            %Draw spectrograms and psd's
            if ~exist('choice','var')
                choice = 'both' ;
            end
            
            switch choice
                case 'main'
                    
                    %Clear axes
                    delete(app.mainSpectAx.Children);
                    delete(app.mainPSDAx.Children);
                    
                    [~,~,app.mainData]=drawSpectrogram(app.mainSpectAx,app.mainPSDAx,app.mainData);
                    app.table2roi(app.mainSpectAx,app.mainData,choice);
                    app.table2roi(app.mainPSDAx,  app.mainData,choice);
                    setupTriggerTime(app,'main');
                case 'mini'
                    delete(app.miniSpectAx.Children);
                    delete(app.miniPSDAx.Children);
                    
                    [~,~,app.miniData]=drawSpectrogram(app.miniSpectAx,app.miniPSDAx,app.miniData);
                    app.table2roi(app.miniSpectAx,app.miniData,choice);
                    app.table2roi(app.miniPSDAx,app.miniData,choice);
                    setupTriggerTime(app,'mini');
                otherwise
                    delete(app.mainSpectAx.Children);
                    delete(app.mainPSDAx.Children);
                    delete(app.miniSpectAx.Children);
                    delete(app.miniPSDAx.Children);
                    
                    [~,~,app.mainData]=drawSpectrogram(app.mainSpectAx,app.mainPSDAx,app.mainData);
                    [~,~,app.miniData]=drawSpectrogram(app.miniSpectAx,app.miniPSDAx,app.miniData);
                    
                    app.table2roi(app.mainSpectAx,app.mainData,'main');
                    app.table2roi(app.mainPSDAx,app.mainData,'main');
                    
                    app.table2roi(app.miniSpectAx,app.miniData,'mini');
                    app.table2roi(app.miniPSDAx,app.miniData,'mini');
                    
                    setupTriggerTime(app,'main');
                    setupTriggerTime(app,'mini');
            end
            if ~isempty(app.mainTimes)&&diff(app.mainTimes)>0
                app.mainSpectAx.XLim = app.mainTimes;
                app.mainPSDAx.XLim = app.mainTimes;
            end
            if ~isempty(app.miniTimes)&&diff(app.miniTimes)>0
                app.miniSpectAx.XLim = app.miniTimes;
                app.miniPSDAx.XLim = app.miniTimes;
            end
            app.updateNavigationPannels;
            app.updataOverlaySniffingPlot(false);
        end
        
        function app = getRoi(app)
            %Get list of ROIs
            ax = app.currentAx;
            col = app.selectedRoiType.Color;
            tag = app.selectedRoiType.Name;
            tagAx = ax.Tag;
            switch tagAx
                case {'1','2'}
                    hObj = app.mainData;
                case {'3','4'}
                    hObj = app.miniData;
            end
            roilist = [];
            rcopyList = [];
            while app.SelectButton.Value
                roi =  drawrectangle(ax,'Color',col,'Label',tag{:});
                
                if ~isvalid(roi)
                    delete(roi)
                    break
                end
                addRoiFunctions(roi,hObj);
                if  isempty(roi.Position)
                    delete(roi)
                    break
                end
                roilist = [roilist;roi]; %#ok
                
                
                rcopyList = app.mirorROI(roi);
                %                 roi.Tag = 'source';
                %                 set(rcopyList,'Tag','copy');
                
            end
            for ir = [rcopyList;roilist]'
                
                switch  ir.Parent.Tag
                    
                    case '1'
                        app.mainData.replaceRowsInTable(ir);
                    case '3'
                        app.miniData.replaceRowsInTable(ir);
                end
            end
            %             hroi = app.findROI(app.currentAx);
            
            
            %             if app.LinkSelectionStateButton.Value
            %                 app.mainData.replaceRowsInTable(roilist);
            %                 app.miniData.replaceRowsInTable(roilist);
            %             else
            %                 switch ax.Tag
            %                     case {'1','2'}
            %                         app.mainData.replaceRowsInTable(hroi);
            %                     case {'3','4'}
            %                         app.miniData.replaceRowsInTable(hroi);
            %                 end
            %             end
            app.SelectButton.Value = false;
        end
        
        
        function hroi = findROI(~,ax)
            hroi = findobj(ax,'Type','images.roi.rectangle');
        end
        
        function app = updateAxBG(app)
            %Axes colors
            selectedColor =     [0.5 0 0.5]; %Magenta
            linkedColor =       [0 0.5 0.5]; %Cyan
            nonSelectedColor =  [0 0 0]; %Black
            
            %Set color of selected axis
            set(app.currentAx,'YColor',selectedColor);
            set(app.currentAx,'XColor',selectedColor);
            set(app.SelectAxesButtonGroup.SelectedObject,'FontColor',selectedColor);
            
            %Set color of connected axes
            set(app.ConnectedAxes,'YColor',linkedColor)
            set(app.ConnectedAxes,'XColor',linkedColor)
            set(app.SelectAxesButtonGroup.Buttons(str2double({app.ConnectedAxes.Tag})),'FontColor',linkedColor);
            
            
            %Set color of non selected axes
            nonSelectedInd = ~any(str2double({app.currentAx.Tag,app.ConnectedAxes.Tag})==(1:4)',2);
            nonSelectedAx = app.allAxes(nonSelectedInd);
            if ~isempty(nonSelectedAx)
                set(nonSelectedAx,'YColor',nonSelectedColor);
                set(nonSelectedAx,'XColor',nonSelectedColor);
            end
            set(app.SelectAxesButtonGroup.Buttons(nonSelectedInd'),'FontColor',nonSelectedColor);
        end
        
        
        function app = updateNavigationPannels(app)
            if ~isempty(app.mainData)
                app.mainFileName.Text = app.mainData.info.fileName;
                if ~isempty(app.mainData.times_)
                    app.mainStart.Value = app.mainData.times_(1);
                    app.mainEnd.Value = app.mainData.times_(2);
                    app.mainTimes = app.mainData.times_;
                end
                
            end
            
            if ~isempty(app.miniData)
                app.miniFileName.Text = app.miniData.info.fileName;
                if ~isempty(app.miniData.times_)
                    app.miniStart.Value = app.miniData.times_(1);
                    app.miniEnd.Value = app.miniData.times_(2);
                    app.miniTimes = app.miniData.times_;
                end
                
            end
            
        end
        
        function [ret] = uptateEnding(~,value,dataIn)
            ret = false;
            len = dataIn.audioLen;
            t = dataIn.times;
            
            if value<=len&&value>t(1)
                t(end) = value;
                dataIn.times_ = t;
                ret = true;
            end
        end
        
        function [ret,dataIn] = updateStart(~,value,dataIn)
            ret = false;
            t = dataIn.times;
            
            if value>=0&&value<t(2)
                t(1) = value;
                dataIn.times_ = t;
                ret = true;
            end
        end


        function app = table2roi(app,ax,source,choice)
            
            %Delete existing rois
            delete(findobj(ax,'Type','images.roi.rectangle'));


            %Redraw
            tab = source.roiTable;
            
            labels = tab.Label;
            if isempty(labels)
                return
            end
            
            labelColors = source.allROIs;
            switch choice
                case 'main'
                    timeRange = app.mainTimes;
                    hObj = app.mainData;
                case 'mini'
                    timeRange = app.miniTimes;
                    hObj = app.miniData;
                otherwise
                    timeRange = app.mainTimes;
            end
            %             timeRange = source.times;
            
            
            positions = [tab.TimeStart,tab.FrLow,tab.TimeEnd-tab.TimeStart,tab.FrHigh-tab.FrLow];
            
            
            sz = size(positions,1);
            for i = 1:sz
                if timeRange(1)<=positions(i,1)&&timeRange(2)>=(positions(i,1)+positions(i,3))
                    
                    thisLabels = labels(i,1);
                    col =labelColors{ismember(labelColors.Name,thisLabels),'Color'};
                    if size(col,1)>1
                        warning('Multiple labels with same name')
                        col = col(1,:);
                    end
                    if isempty(col)
                        col = [0 0 0] ;
                        warning('Could not find lable "%s", and was added',thisLabels)
                        source.addedTypeList = {thisLabels,col,""};
                        app.updateROITypeList;
                    end
                    switch ax.Tag
                        case {'2','4'}
                            positions(i,[2,4]) = [ax.YLim(1),ax.YLim(2)-ax.YLim(1)];
                    end
                    
                    hROI = drawrectangle('Parent',ax,"Position",positions(i,:),"Label",labels(i,:),"Color",col) ;
                    addRoiFunctions(hROI,hObj)
                end
            end
        end
        
        function rcopyList =mirorROI (app,rArr)
            for r = rArr
                axDest = app.ConnectedAxes;
                axCurr = app.currentAx;
                %Translate x position to time
                rcopyList = [];
                curTag = axCurr.Tag;
                
                pos = r.Position;
                
                
                for iax = 1:size(axDest,1)
                    posTemp = pos;
                    thisAx = axDest(iax);
                    thisTag = thisAx.Tag;
                    if ((curTag=='1')||(curTag=='2'))
                        if ((thisTag=='1')||(thisTag=='2'))
                            %No shift
                        else
                            posTemp(1) = pos(1) - app.mainData.triggerTime + app.miniData.triggerTime ;
                            cm = get(r,'uicontextmenu');
                            cm.UserData.hObj = app.miniData;
                        end
                    else
                        if ((thisTag=='3')||(thisTag=='4'))
                            %No shift
                        else
                            posTemp(1) = pos(1) + app.mainData.triggerTime - app.miniData.triggerTime ;
                            cm = get(r,'uicontextmenu');
                            cm.UserData.hObj = app.mainData;
                        end
                        
                        
                    end


                    xScale = diff(axCurr.XLim)/diff(thisAx.XLim);
                    
                    
                    newPos = posTemp./xScale;
                    
                    newPos([2,4]) = thisAx.YLim; %Change horizontal lines but keep the vertical limits
                    
                    r.Position = newPos;
                    rcopy = copyobj(r,thisAx);
                    rcopyList = [rcopyList;rcopy]; %#ok
                    r.Position = pos;
                end
                
            end
        end


        function app = jumpToSyllable(app,choice,direction)
            switch choice
                case 'both'
                    if ~app.LinkNavigationStateButton.Value
                        jumpToSyllable(app,'main',direction)
                        jumpToSyllable(app,'mini',direction)
                        return
                    else
                        data = app.mainData;
                        tab = data.roiTable;
                    end
                case 'main'
                    data = app.mainData;
                    tab = data.roiTable;
                case 'mini'
                    data = app.miniData;
                    tab = data.roiTable;
            end
            if isempty(tab)
                return
            end
            roiTimes = tab.TimeStart;
            roiDurations = (tab.Duration)/2; %#ok For option 1
            timeRagneFactor = 3;             %#ok For option 1
            %             timeRangeToJump = 0.1;             %For option 2
            timeRangeToJump = app.jumpRange;
            currentTime = data.times;
            %             currentRange = diff(currentTime);
            
            switch direction
                case 'forward'
                    indNextTime = find(roiTimes-currentTime(2)>0,1,"first");
                    if isempty(indNextTime)
                        return
                    else
                        nextTime = roiTimes(indNextTime);
                        %Opstion 1 - range dependant on duration
                        %halfDurationOfSyllable = roiDurations(indNextTime);
                        %tempTimes = [nextTime-(timeRagneFactor-1)*halfDuratonOfSyllable,nextTime+halfDurationOfSyllable*timeRagneFactor];
                        
                        %Option 2 - range constant - +-timeRangeToJump sec + duration
                        tempTimes = [nextTime-timeRangeToJump,nextTime+timeRangeToJump];
                    end
                case 'backward'
                    indPrevTime = find(currentTime(1)-roiTimes>0,1,"last");
                    if isempty(indPrevTime)
                        return
                    else
                        prevTime = roiTimes(indPrevTime);
                        %Option 1
                        %halfDurationOfSyllable = roiDurations(indPrevTime);
                        %tempTimes = [prevTime-(timeRagneFactor-1)*halfDurationOfSyllable,prevTime+halfDurationOfSyllable*timeRagneFactor];
                        
                        %Option 2
                        tempTimes = [prevTime-timeRangeToJump,prevTime+timeRangeToJump];
                    end
                    
            end
            
            switch choice
                case 'main'
                    app.mainTimes = tempTimes;
                    if app.LinkNavigationStateButton.Value
                        app.miniTimes = tempTimes - app.mainData.triggerTime + app.miniData.triggerTime;
                        app.updatePlots
                    else
                        app.updatePlots(choice)
                    end
                case 'mini'
                    app.miniTimes = tempTimes;
                    app.updatePlots(choice)
            end
            
            
        end
        
        function [hDataRet,ret] = loadFile(~,hData)
            ret = false;
            hDataRet = [];


            [file,path] = uigetfile({'*.mat;*.wav','All Files (*.mat,*.wav)';...
                '*.wav','Audio File (.*wav)';...
                '*.mat','MATLAB File (*.mat)'});
            if file==0 %If the load was cancelled
                return
            else
                [~,f,type]=fileparts(file);
                switch type
                    case '.mat'
                        readfile = matfile(fullfile(path,file));
                        classesInVar = whos(readfile);
                        %Check if there is an audioClip variable in the matfile
                        indAudioClip = find(strcmpi('audioClip',{classesInVar.class}),1,'first');
                        if ~isempty(indAudioClip)
                            hDataRet  = eval(['readfile.',classesInVar(indAudioClip).name]);
                            if ~isempty(hDataRet)
                                delete(hData)
                                ret = true;
                            end
                            
                            return
                        end
                        %Check if there is a double 1xn array variable in the matfile
                        indDouble = find(strcmpi('double',{classesInVar.class}),1,'first');
                        if ~isempty(indDouble)
                            vec  = eval(['readfile.',classesInVar(indDouble).name]);
                            
                            if isvector(vec)
                                if size(vec,1)<2
                                    vec = reshape(vec,[],1);
                                end
                                dataIn = getRecodringDetails(f,path,file);
                                hDataRet = audioClip(vec,dataIn);
                                hDataRet.sourceType = 'mat';
                                delete(hData)
                                ret = true;
                                return
                            else
                                warning('Unable to find a vector to load')
                                return
                            end
                        end
                        return
                    case '.wav'
                        vec = audioread(fullfile(path,file));
                        
                        %Get details from file name
                        dataIn = getRecodringDetails(f,path,file);
                        hDataRet = audioClip(vec,dataIn);
                        delete(hData)
                        ret = true;
                        return
                        
                end
                
            end
        end
        function getTriggerTime(~,ax,d,hbox,l)
            if isempty(d)
                d = drawpoint(ax);
            end
            
            if isempty(l)
                l = xline(d.Position(1));
            end
            
            d.UserData = {l,hbox};
            addlistener(d,'MovingROI',@myCallbk);
            
            
            function  myCallbk(s,evtData)
                %                 eventSource = evtData.Source;
                %                 eventName = evtData.EventName;
                %                 previousCenter = evtData.PreviousPosition;
                currentCenter = evtData.CurrentPosition;
                l_ = s.UserData{1} ;
                hbox_ = s.UserData{2} ;
                l_.Value = currentCenter(1);
                hbox_.Value = currentCenter(1);
            end
        end
        
        function setupTriggerTime(app,choice)
            if ~exist("choice",'var')||isempty(choice)
                choice = 'both' ;
            end
            switch choice
                case 'both'
                    setupTriggerTime(app,'main')
                    setupTriggerTime(app,'mini')
                    return
                case 'main'
                    ax = app.mainSpectAx;
                    hbox = app.mainTriggerTimeEditField;
                    hTriggerTime = app.mainTriggerTime;
                    obj = app.mainData;
                case 'mini'
                    ax = app.miniSpectAx;
                    hbox = app.miniTriggerTimeEditField;
                    hTriggerTime = app.miniTriggerTime;
                    obj = app.miniData;
            end
            
            midVal = mean(ax.YLim);
            
            if isfield(hTriggerTime,'Line')&&isvalid(hTriggerTime.Line)
                hLine = hTriggerTime.Line;
                hLine.Value = hTriggerTime.Value;
            else
                hLine = xline(ax,hTriggerTime.Value,'Label','TriggerTime','LabelVerticalAlignment','middle');
                hTriggerTime.Line = hLine;
            end
            
            if isfield(hTriggerTime,'Point')&&isvalid(hTriggerTime.Point)
                hPoint = hTriggerTime.Point;
                hPoint.Position(1) = hTriggerTime.Value;
            else
                hPoint = drawpoint(ax,'Position', [hTriggerTime.Value midVal]);
                hTriggerTime.Point= hPoint;
                hPoint.UserData = {hLine,hbox};
                addlistener(hPoint,'MovingROI',@(s,evtData) myCallbk(s,evtData,app,choice));
            end
            
            
            obj.triggerTime = hTriggerTime.Value;
            switch choice
                case 'main'
                    app.mainTriggerTime = hTriggerTime;
                case 'mini'
                    app.miniTriggerTime = hTriggerTime;
            end
            
            function  myCallbk(s,evtData,app,choice)
                currentCenter = evtData.CurrentPosition;
                
                switch choice
                    case 'main'
                        app.mainTriggerTimeEditField.Value = currentCenter(1);
                        app.mainTriggerTime.Line.Value = currentCenter(1);
                        app.mainData.triggerTime = currentCenter(1);
                        app.mainTriggerTime.Value = currentCenter(1);
                        
                    case 'mini'
                        app.miniTriggerTimeEditField.Value = currentCenter(1);
                        app.miniTriggerTime.Line.Value = currentCenter(1);
                        app.miniData.triggerTime = currentCenter(1);
                        app.miniTriggerTime.Value = currentCenter(1);
                end


                l_ = s.UserData{1} ;
                hbox_ = s.UserData{2} ;
                l_.Value = currentCenter(1);
                hbox_.Value = currentCenter(1);
            end
        end


        function updateROITypeList(app)
            %             combinedRois = join(app.mainData.allROIs,app.miniData.allROIs,'Keys','Name');
            combinedRois = [app.mainData.allROIs;app.miniData.allROIs];
            [uniqueNames,iNames] = unique(combinedRois.Name);
            app.ROItypesListBox.Items = uniqueNames;
            app.roiTypeList = combinedRois(iNames,:);
            app.selectedRoiType = app.roiTypeList(1,:);
        end
        
        function changePannelState(app,children,state)
            
            for i = 1:size(children)
                
                children(i).Enable = state;
                
                
            end
        end
        
        
        function updateSelection(app)
            %Option 1: update only current axis
            %Option 2: update all axis


            %Translate the roi point into seconds
            if app.LinkSelectionStateButton.Value||1 %this is option 2! update all axes
                
                hroi1 = findobj([app.mainSpectAx],'Type','images.roi.rectangle');
                app.mainData.replaceRowsInTable(hroi1,true);
                app.updatePlots('main')
                
                hroi2 = findobj([app.miniSpectAx],'Type','images.roi.rectangle');
                app.miniData.replaceRowsInTable(hroi2,true);
                app.updatePlots('mini')
            else
                tag = str2double(app.currentAx.Tag);
                switch tag
                    case {1,2}
                        hroi = findobj([app.mainSpectAx],'Type','images.roi.rectangle');
                        app.mainData.replaceRowsInTable(hroi,true);
                        app.updatePlots('main')
                    case {3,4}
                        hroi = findobj([app.miniSpectAx],'Type','images.roi.rectangle');
                        app.miniData.replaceRowsInTable(hroi,true);
                        app.updatePlots('mini')
                end
            end
            
        end
        
        function updataOverlaySniffingPlot(app,openIfClosedFlag)
            appHandle = app.overlayAppHandle;
            
            if ~exist('openIfClosedFlag','var')
                openIfClosedFlag = false;
            end
            
            if (isempty(appHandle)||~isvalid(appHandle))
                if openIfClosedFlag
                    app.overlayAppHandle = overlaySniffingOnSpect(app.mainData,app.miniData);
                end
            else
                appHandle.updatePlot();
            end
            
        end
        
        function movePlot(app,choice,direction)
            switch choice
                case 'both'
                    movePlot(app,'main',direction)
                    movePlot(app,'mini',direction)
                    return
                case 'main'
                    t = app.mainTimes;
                    stepVal = app.mainStepSizeSpinner.Value;
                    sourceData = app.mainData;
                case 'mini'
                    t = app.miniTimes;
                    stepVal = app.miniStepSizeSpinner.Value;
                    sourceData = app.miniData;
                    
            end
            
            %             audioLen = sourceData.audioLen;
            audioLen = inf;
            switch direction
                case 'forward'
                    newT = t + stepVal;
                    validFlag = newT(2)<=audioLen;
                case 'backward'
                    newT = t - stepVal;
                    validFlag = newT(1)>=0;
            end
            if validFlag
                switch choice
                    case 'main'
                        app.mainTimes = newT;
                        app.mainStart.Value = newT(1);
                        app.mainEnd.Value = newT(2);
                        app.updatePlots('main');
                        
                        if app.LinkNavigationStateButton.Value
                            app.miniTimes = newT - app.mainData.triggerTime + app.miniData.triggerTime;
                            app.updatePlots('mini');
                        end
                        
                    case 'mini'
                        app.miniTimes = newT;
                        app.miniStart.Value = newT(1);
                        app.miniEnd.Value = newT(2);
                        app.updatePlots('mini');
                end
                
            else
                switch choice
                    case 'main'
                        switch direction
                            case 'forward'
                                tempBG = app.mainForward.BackgroundColor;
                                app.mainForward.BackgroundColor = 'red';
                                drawnow
                                pause(0.1)
                                app.mainForward.BackgroundColor = tempBG;
                            case 'backward'
                                tempBG = app.mainBack.BackgroundColor;
                                app.mainBack.BackgroundColor = 'red';
                                drawnow
                                pause(0.1)
                                app.mainBack.BackgroundColor = tempBG;
                        end
                        
                    case 'mini'
                        switch direction
                            case 'forward'
                                tempBG = app.miniForward.BackgroundColor;
                                app.miniForward.BackgroundColor = 'red';
                                drawnow
                                pause(0.1)
                                app.miniForward.BackgroundColor = tempBG;
                            case 'backward'
                                tempBG = app.miniBack.BackgroundColor;
                                app.miniBack.BackgroundColor = 'red';
                                drawnow
                                pause(0.1)
                                app.miniBack.BackgroundColor = tempBG;
                        end
                end
                
            end
            
        end
        
        function zoomInOut(app,choice,direction)
            %Add zoom out or in by 10% on X scale
            changeRatio = 0.2/2;


            switch direction
                case 'in'
                    switch choice
                        case 'both'
                            zoomInOut(app,'main',direction)
                            zoomInOut(app,'mini',direction)
                            return
                        case 'main'
                            timesRange = app.mainTimes;
                            changeValue = diff(timesRange)*changeRatio;
                            newTimesRange = [timesRange(1)+changeValue,timesRange(2)-changeValue];
                            app.mainTimes = newTimesRange;
                        case 'mini'
                            timesRange = app.miniTimes;
                            changeValue = diff(timesRange)*changeRatio;
                            newTimesRange = [timesRange(1)+changeValue,timesRange(2)-changeValue];
                            app.miniTimes = newTimesRange;
                    end
                case 'out'
                    switch choice
                        case 'both'
                            zoomInOut(app,'main',direction)
                            zoomInOut(app,'mini',direction)
                            return
                        case 'main'
                            timesRange = app.mainTimes;
                            changeValue = diff(timesRange)*changeRatio;
                            newTimesRange = [timesRange(1)-changeValue,timesRange(2)+changeValue];
                            app.mainTimes = newTimesRange;
                        case 'mini'
                            timesRange = app.miniTimes;
                            changeValue = diff(timesRange)*changeRatio;
                            newTimesRange = [timesRange(1)-changeValue,timesRange(2)+changeValue];
                            app.miniTimes = newTimesRange;
                    end
                    
            end
            app.updatePlots(choice)
        end
        
        function updateAppValuesFromObj(app,choice)
            switch choice
                case 'both'
                    updateAppValuesFromObj(app,'main')
                    updateAppValuesFromObj(app,'mini')
                    return
                case 'main'
                    obj = app.mainData;
                    app.mainTimes = obj.times_;
                    app.mainTriggerTime.Value = obj.triggerTime;
                    app.mainTriggerTimeEditField.Value = obj.triggerTime;
                case 'mini'
                    obj = app.miniData;
                    app.miniTimes = obj.times_;
                    app.miniTriggerTime.Value = obj.triggerTime;
                    app.miniTriggerTimeEditField.Value = obj.triggerTime;
                    
            end
            
        end
        
        function existsInWorkspace = checkIfObjectExistInWorkspace(app,choice)
            existsInWorkspace = false;
            switch choice
                case 'main'
                    obj = app.mainData;
                case 'mini'
                    obj = app.miniData;
            end
            vars = evalin('base', 'whos');
            if ~isempty(vars)
                viableClasses = {'audioClip'};
                vars = {vars(ismember({vars.class},viableClasses)).name}';
                
                
                for i = 1:size(vars,1)
                    if (evalin('base',vars{i})==obj)
                        existsInWorkspace = true;
                        break
                    end
                end
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainData, miniData)
            
            app.currentAx = app.mainSpectAx;
            app.ConnectedAxes = app.mainPSDAx;
            app.allAxes = [app.mainSpectAx;app.mainPSDAx;app.miniSpectAx;app.miniPSDAx];
            app.mainSpectAx.YLim = [0 125000];
            app.miniSpectAx.YLim = [0 125000];
            updateAxBG(app);
            
            
            if nargin == 1
                app.mainData = audioClip;
                app.miniData = audioClip;
            end
            
            
            if ~exist('mainData','var')
                mainData = audioClip;
            end
            
            if  ~exist('miniData','var')
                miniData = audioClip;
            end


            updateROITypeList(app);
            app.selectedRoiType = app.roiTypeList(1,:);
            
            app.mainVecSource = mainData.vecSource;
            app.miniVecSource = miniData.vecSource;
            
            app.mainData = mainData;
            app.miniData = miniData;
            
            app.updateROITypeList;
            
            app.mainTriggerTime.Value = mainData.triggerTime;
            app.miniTriggerTime.Value = miniData.triggerTime;
            
            app.updatePlots();


            app.updateNavigationPannels();
            
            app.mainTriggerTime.Value = mainData.triggerTime;
            app.mainTriggerTimeEditField.Value = mainData.triggerTime;
            
            app.miniTriggerTime.Value = miniData.triggerTime;
            app.miniTriggerTimeEditField.Value = miniData.triggerTime;
            
            app.setupTriggerTime('both');


        end

        % Value changed function: SelectButton
        function SelectButtonValueChanged(app, event)
            value = app.SelectButton.Value;
            
            
            allEnabledComponents = findobj(app.UIFigure,'Enable','on');
            if value
                updateAxBG(app);
                
                app.changePannelState(allEnabledComponents,'off')
                app.SelectButton.Enable = 'on';
                %                 app.CenterPanel.Enable = 'on';
                getRoi(app);
                
                app.changePannelState(allEnabledComponents,'on')
                
            else
                alldissabledComponents = findobj(app.UIFigure,'Enable','off');
                
                if app.LinkNavigationStateButton.Value
                    %Exclude miniNavigation components
                    c = alldissabledComponents(~ismember(alldissabledComponents,app.miniNavigation));
                    app.changePannelState(c,'on');
                else
                    app.changePannelState(alldissabledComponents,'on')
                end
                
            end
            updateSelection(app);
        end

        % Selection changed function: SelectAxesButtonGroup
        function SelectAxesButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectAxesButtonGroup.SelectedObject;
            switch selectedButton.Tag
                case '1'
                    app.currentAx = app.allAxes(1);
                    if ~app.LinkSelectionStateButton.Value
                        app.ConnectedAxes = app.allAxes(2);
                    else
                        app.ConnectedAxes = app.allAxes([2,3,4]);
                    end
                case '2'
                    app.currentAx = app.allAxes(2);
                    if ~app.LinkSelectionStateButton.Value
                        app.ConnectedAxes = app.allAxes(1);
                    else
                        app.ConnectedAxes = app.allAxes([1,3,4]);
                    end
                case '3'
                    app.currentAx = app.allAxes(3);
                    if ~app.LinkSelectionStateButton.Value
                        app.ConnectedAxes = app.allAxes(4);
                    else
                        app.ConnectedAxes = app.allAxes([1,2,4]);
                    end
                case '4'
                    app.currentAx = app.allAxes(4);
                    if ~app.LinkSelectionStateButton.Value
                        app.ConnectedAxes = app.allAxes(3);
                    else
                        app.ConnectedAxes = app.allAxes([1,2,3]);
                    end
            end
            updateAxBG(app);
        end

        % Value changed function: LinkSelectionStateButton
        function LinkSelectionStateButtonValueChanged(app, event)
            value = app.LinkSelectionStateButton.Value;
            switch app.currentAx.Tag
                case '1'
                    app.currentAx = app.allAxes(1);
                    if ~value
                        app.ConnectedAxes = app.allAxes(2);
                    else
                        app.ConnectedAxes = app.allAxes([2,3,4]);
                    end
                case '2'
                    app.currentAx = app.allAxes(2);
                    if ~value
                        app.ConnectedAxes = app.allAxes(1);
                    else
                        app.ConnectedAxes = app.allAxes([1,3,4]);
                    end
                case '3'
                    app.currentAx = app.allAxes(3);
                    if ~value
                        app.ConnectedAxes = app.allAxes(4);
                    else
                        app.ConnectedAxes = app.allAxes([1,2,4]);
                    end
                case '4'
                    app.currentAx = app.allAxes(4);
                    if ~value
                        app.ConnectedAxes = app.allAxes(3);
                    else
                        app.ConnectedAxes = app.allAxes([1,2,3]);
                    end
            end
            updateAxBG(app);
        end

        % Button pushed function: ClearROIsButton
        function ClearROIsButtonPushed(app, event)
            fig = uifigure;
            answer = uiconfirm(fig,'Select axes from which to delete ROIs:', ...
                'Delete ROIs', ...
                'Options',{'Main','Mini','Cancel'},...
                'DefaultOption',3,'CancelOption',3);
            close(fig);
            switch answer
                case 'Main'
                    h =  findobj([app.mainSpectAx,app.mainPSDAx],'Type','images.roi.rectangle');
                    obj = app.mainData;
                    hTab = obj.roi2table(h);
                    obj.deleteRowsFromTableByRange(obj.times,hTab.Label);
                    delete(h)
                    
                case 'Mini'
                    h =  findobj([app.miniSpectAx,app.miniPSDAx],'Type','images.roi.rectangle');
                    obj = app.miniData;
                    hTab = obj.roi2table(h.Label);
                    obj.deleteRowsFromTableByRange(obj.times,hTab);
                    delete(h)
                otherwise
                    return
            end
        end

        % Value changed function: ROItypesListBox
        function ROItypesListBoxValueChanged(app, event)
            value = app.ROItypesListBox.Value;
            app.selectedRoiType = app.roiTypeList(strcmp(app.roiTypeList.Name,value),:);
        end

        % Button pushed function: UpdateSelectionButton
        function UpdateSelectionButtonPushed(app, event)
            app.updateSelection;
        end

        % Value changed function: mainEnd
        function mainEndValueChanged(app, event)
            value = app.mainEnd.Value;
            tempTimes = app.mainTimes;
            app.mainTimes = [tempTimes(1),value];
            
            if app.LinkNavigationStateButton.Value
                app.miniTimes = app.mainTimes - app.mainData.triggerTime + app.miniData.triggerTime;
            end
        end

        % Value changed function: mainStart
        function mainStartValueChanged(app, event)
            value = app.mainStart.Value;
            tempTimes = app.mainTimes;
            app.mainTimes = [value,tempTimes(2)];
            
            if app.LinkNavigationStateButton.Value
                app.miniTimes = app.mainTimes - app.mainData.triggerTime + app.miniData.triggerTime;
            end
            
            
        end

        % Button pushed function: mainRefreshPlot
        function mainRefreshPlotButtonPushed(app, event)
            if app.LinkNavigationStateButton.Value
                app.miniData.times_ = app.mainData.times_ - app.mainData.triggerTime + app.miniData.triggerTime;
                app.miniTimes = app.mainTimes - app.mainData.triggerTime + app.miniData.triggerTime;
                app.updatePlots;
            else
                app.updatePlots('main');
            end
            
        end

        % Value changed function: miniEnd
        function miniEndValueChanged(app, event)
            value = app.miniEnd.Value;
            tempTimes = app.miniTimes;
            app.miniTimes = [tempTimes(1),value];
            
            
        end

        % Value changed function: miniStart
        function miniStartValueChanged(app, event)
            value = app.miniStart.Value;
            tempTimes = app.miniTimes;
            app.miniTimes = [value,tempTimes(2)];
        end

        % Button pushed function: miniRefreshPlot
        function miniRefreshPlotButtonPushed(app, event)
            app.updatePlots('mini')
        end

        % Button pushed function: mainForward
        function mainForwardButtonPushed(app, event)
            app.movePlot('main','forward');
        end

        % Button pushed function: mainBack
        function mainBackButtonPushed(app, event)
            app.movePlot('main','backward');
        end

        % Button pushed function: miniForward
        function miniForwardButtonPushed(app, event)
            app.movePlot('mini','forward');
        end

        % Button pushed function: miniBack
        function miniBackButtonPushed(app, event)
            app.movePlot('mini','backward');
        end

        % Button pushed function: mainLoadFileButton
        function mainLoadFileButtonPushed(app, event)
            
            fig = uifigure;
            answer = uiconfirm(fig,'Please Select Source:', ...
                'Load File/Variable', ...
                'Options',{'From File','From Workspace','Cancel'},...
                'DefaultOption',3,'CancelOption',3);
            close(fig);
            switch answer
                case 'From File'
                    hData = app.mainData;
                    [hDataRet,ret] = app.loadFile(hData);
                    
                    if ret
                        app.mainData = hDataRet;
                        app.mainVecSource = 'file';
                    end
                case 'From Workspace'
                    h = loadVariabFromWorkspace(app,'main');
                    waitfor(h);
                case 'Cancel'
                    return
            end
            app.updateAppValuesFromObj('main');
            updatePlots(app,'main');
            updateROITypeList(app);
            figure(app.UIFigure)
        end

        % Button pushed function: miniLoadFileButton
        function miniLoadFileButtonPushed(app, event)
            fig = uifigure;
            answer = uiconfirm(fig,'Please Select Source:', ...
                'Load File/Variable', ...
                'Options',{'From File','From Workspace','Cancel'},...
                'DefaultOption',3,'CancelOption',3);
            close(fig);
            switch answer
                case 'From File'
                    hData = app.miniData;
                    [hDataRet,ret] = app.loadFile(hData);
                    if ret
                        app.miniData = hDataRet;
                        app.miniVecSource = 'file';
                    end
                case 'From Workspace'
                    h = loadVariabFromWorkspace(app,'mini');
                    waitfor(h);
                case 'Cancel'
                    return
                    
            end
            app.updateAppValuesFromObj('mini');
            updatePlots(app,'mini');
            updateROITypeList(app);
            figure(app.UIFigure)
        end

        % Button pushed function: ReloadROIButton
        function ReloadROIButtonPushed(app, event)
            if app.LinkNavigationStateButton.Value
                choice = 'main';
                app.table2roi(app.mainSpectAx,app.mainData,choice);
                app.table2roi(app.miniSpectAx,app.miniData,choice);
            else
                switch app.currentAx.Tag
                    
                    case {'1','2'}
                        choice = 'main';
                        app.table2roi(app.mainSpectAx,app.mainData,choice);
                    case {'3','4'}
                        choice = 'mini';
                        app.table2roi(app.miniSpectAx,app.miniData,choice);
                end
                
                
            end
            
        end

        % Button pushed function: ExportSaveButton
        function ExportSaveButtonPushed(app, event)
            labels = {'Main MIC data','Mini MIC data','main figure','mini figure'};
            vars = {'MainAudio', 'MiniAudio', 'mainFigure','miniFigure'};
            vals = {app.mainData,app.miniData,app.mainSpectAx,app.miniSpectAx};
            titleTxt = 'Choose variable to export to workspace';
            defs = logical([0 0 0 0]);
            helpfcn='';
            flist = {...
                {@saveVariables,app,'main'},...
                {@saveVariables,app,'mini'},...
                {@copyFigure,app.mainSpectAx,app.mainData.info.fileName},...
                {@copyFigure,app.miniSpectAx,app.miniData.info.fileName}...
                };
            export2wsdlg(labels,vars,vals,titleTxt,defs,helpfcn,flist);
            
            function hFigure = copyFigure(hAx,name)
                hFigure = figure;
                axNew = axes;
                % Copy all objects from UIAxes to new axis
                copyobj(hAx.Children,axNew)
                axNew.XLim = hAx.XLim;
                axNew.YLim = hAx.YLim;
                axNew.CLim = hAx.CLim;
                axNew.Title = hAx.Title;
                axNew.XLabel = hAx.XLabel;
                axNew.YLabel = hAx.YLabel;
                title(axNew,name)
                
            end
            function ret = saveVariables(app,choice)
                
                
                switch choice
                    
                    case 'main'
                        vecsoucre = app.mainVecSource;
                        obj = app.mainData;
                        
                        
                    case 'mini'
                        vecsoucre = app.miniVecSource;
                        obj = app.miniData;
                end
                
                [...
                    AllClustersForFile,...
                    AudioFullName_tSNE_ClusterAnalysis,...
                    AllClustersForFileSyllables,...
                    AllClustersDurationForFile,...
                    TriggerTime,...
                    LablesMap] = prepareTabForDeepPheno(obj);
                
                
                ret.AllClustersForFile = AllClustersForFile;
                ret.AudioFullName_tSNE_ClusterAnalysis = AudioFullName_tSNE_ClusterAnalysis;
                ret.AllClustersForFileSyllables = AllClustersForFileSyllables;
                ret.AllClustersDurationForFile = AllClustersDurationForFile;
                ret.TriggerTime = TriggerTime;
                ret.LablesMap = LablesMap;
                
                ret.obj = obj;
                %
                %                 savePath = 'savedFiles';
                %                 if ~exist(savePath,'dir')
                %                     mkdir(savePath)
                %                 end
                
                [~,fileName]=fileparts(obj.info.fileName);
                if isempty(fileName)
                    fileName = 'audioClip';
                end


                fileName = sprintf('%s_Data_%s',fileName, datestr(now,'mm-dd-yyyy HH-MM'));
                
                %                 tempFullFileName = fullfile(savePath,fileName);
                
                [file,path,saveFlag] = uiputfile('*.mat','Save File',fileName);
                if saveFlag
                    fullFileName = fullfile(path,file);
                    %If the file was loaded from workspace, also save the
                    %audio vector and update path
                    switch vecsoucre
                        case 'workspace'
                            vec = obj.vec;
                            ret.vec = vec;
                            obj.sourceType = 'mat';
                            
                            [~,f,~]=fileparts(fullFileName);
                            f =[f,'_audioVec'];
                            audioFileName = [f,'.mat'];
                            
                            
                            info = getRecodringDetails(audioFileName,path,f);
                            obj.info = info;
                            save(fullfile(path,audioFileName),'vec');
                            
                    end
                    save(fullFileName,'AllClustersForFile','AudioFullName_tSNE_ClusterAnalysis','AllClustersForFileSyllables','AllClustersDurationForFile','TriggerTime','LablesMap','obj','-v7.3');
                    
                end
            end
        end

        % Button pushed function: mainNextButton
        function mainNextButtonPushed(app, event)
            choice = 'main';
            direction = 'forward';
            app.jumpToSyllable(choice,direction);
        end

        % Button pushed function: mainPrevButton
        function mainPrevButtonPushed(app, event)
            choice = 'main';
            direction = 'backward';
            jumpToSyllable(app,choice,direction);
        end

        % Button pushed function: miniNextButton
        function miniNextButtonPushed(app, event)
            choice = 'mini';
            direction = 'forward';
            app.jumpToSyllable(choice,direction);
        end

        % Button pushed function: miniPrevButton
        function miniPrevButtonPushed(app, event)
            choice = 'mini';
            direction = 'backward';
            jumpToSyllable(app,choice,direction);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~checkIfObjectExistInWorkspace(app,'main')
                delete(app.mainData)
            end
            if ~checkIfObjectExistInWorkspace(app,'mini')
                delete(app.miniData)
            end
            delete(app.overlayAppHandle)
            delete(app)
        end

        % Button pushed function: AutoDetectionButton
        function AutoDetectionButtonPushed(app, event)
            switch app.currentAx.Tag
                case {'1','2'}
                    source = app.mainData;
                case {'3','4'}
                    source = app.miniData;
            end
            if isempty(source)
                return
            end
            fig = uifigure;
            answer = uiconfirm(fig,'Please Select Range:', ...
                'Auto Detection of Syllables', ...
                'Options',{'All','Current','Cancel'},...
                'DefaultOption',3,'CancelOption',3);
            close(fig);
            switch answer
                case 'All'
                    times = 0;
                    data = source.vec;
                case 'Current'
                    times = source.times;
                    fs = source.fs;
                    data = source.vec(round((1+times(1)*fs):(times(2)*fs)));
                otherwise
                    return
            end
            
            if isempty(app.detectionNetwork)
                try
                    net = matfile(fullfile('autodetection','best_net_27092020.mat'));
                    app.detectionNetwork = net.net;
                catch
                    warning('Unable to find net, please choose net to load:')
                    [file,path] = uigetfile('*.mat');
                    net = matfile(fullfile(path,file));
                    try
                        net = net.net;
                    catch
                        error('Must have a variable names -net- ')
                    end
                    app.detectionNetwork = net;
                end
                
            end
            if isempty(app.definedFilter)
                app.definedFilter = define_a_filter(11,8);
            end
            
            data = data./max(abs(data));
            ret = extract_features_from_large_filesWraper(data,times(1),app.definedFilter,app.detectionNetwork) ;
            roiTable = source.roi2table(ret);
            source.replaceRowsInTable(roiTable);
            app.updatePlots();
        end

        % Callback function
        function RenameMenuSelected(app, event)
            selected = app.ROItypesListBox.Value;
            protected = cellstr(audioClip.roiTypeList.Name);
            switch selected
                case protected
                    msg = sprintf("You can't rename or remove\na default ROI type");
                    msgbox(msg)
                otherwise
                    prompt = sprintf('Please enter the new name: ');
                    dlgtitle = 'Rename ROI type';
                    newName = inputdlg(prompt,dlgtitle);
                    if ~isempty(newName)
                        ind = strcmpi(selected,app.ROItypesListBox.Items);
                        app.ROItypesListBox.Items{ind} = newName{:};
                    end
            end
        end

        % Menu selected function: EditMenu
        function EditMenuSelected(app, event)
            tag = app.currentAx.Tag;
            switch   tag
                case {'1','2'}
                    obj = app.mainData;
                    obj2 = app.miniData;
                case {'3','4'}
                    obj2 = app.mainData;
                    obj = app.miniData;
            end
            h = obj.editROIs;
            waitfor(h);
            obj2.addedTypeList = obj.addedTypeList;
            app.updateROITypeList;
        end

        % Button pushed function: mainSetTriggerButton
        function mainSetTriggerButtonPushed(app, event)
            setupTriggerTime(app,'main');
        end

        % Value changed function: mainTriggerTimeEditField
        function mainTriggerTimeEditFieldValueChanged(app, event)
            value = app.mainTriggerTimeEditField.Value;
            app.mainTriggerTime.Value = value;
            setupTriggerTime(app,'main');
        end

        % Button pushed function: miniSetTriggerButton
        function miniSetTriggerButtonPushed(app, event)
            setupTriggerTime(app,'mini');
        end

        % Value changed function: miniTriggerTimeEditField
        function miniTriggerTimeEditFieldValueChanged(app, event)
            value = app.miniTriggerTimeEditField.Value;
            app.miniTriggerTime.Value = value;
            setupTriggerTime(app,'mini');
        end

        % Value changed function: LinkNavigationStateButton
        function LinkNavigationStateButtonValueChanged(app, event)
            value = app.LinkNavigationStateButton.Value;
            children = get(app.miniNavigation,'Children');
            if value
                app.NavigationMiniMicPanel.BackgroundColor = [0.84 0.94 0.94] ;
                %Dissable mini navigation panel
                app.changePannelState(children,'off')
                
                %Set both axes on the same ragne + trigger time
                mainObj = app.mainData;
                miniObj = app.miniData;
                
                maintimes = app.mainTimes;
                
                maintriggertime = mainObj.triggerTime;
                minitriggertime = miniObj.triggerTime;
                
                minitimes = maintimes + minitriggertime - maintriggertime;
                app.miniTimes = minitimes;
                app.updatePlots;
            else
                app.NavigationMiniMicPanel.BackgroundColor = [0.94 0.94 0.94] ;
                app.changePannelState(children,'on')
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            tag = app.currentAx.Tag;
            switch tag
                case {'1','2'}
                    dataIn = app.mainData;
                case {'3','4'}
                    dataIn = app.miniData ;
            end
            if isempty(dataIn)
                return
            end
            
            h = splitterEditorApp(dataIn);
            waitfor(h);
            app.updatePlots;
        end

        % Button pushed function: OverlaySniffingButton
        function OverlaySniffingButtonPushed(app, event)
            app.updataOverlaySniffingPlot(true);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            %KEYPRESS CALL BACK
            key = event.Key;
            if app.SelectButton.Value %Hot keys dont work in selection mode
                return
            end
            curraxTag = app.currentAx.Tag;
            switch curraxTag
                case {'1','2'}
                    choice = 'main';
                case {'3','4'}
                    choice = 'mini';
            end
            if app.LinkNavigationStateButton.Value
                choice = 'both' ;
            end
            eventModifier = event.Modifier;
            
            if isempty(eventModifier)
                switch key
                    case 'rightarrow'
                        app.movePlot(choice,'forward')
                    case 'leftarrow'
                        app.movePlot(choice,'backward')
                    case 'r'
                        roilist = app.findROI(app.currentAx);
                        if ~isempty(roilist)
                            for r = 1:size(roilist,1)
                                roilist(r).Label = 'Noise';
                                roilist(r).StripeColor = 'red';
                                roilist(r).Color = 'black';
                            end
                        end
                        
                end
                
            else
                if iscell(eventModifier)
                    eventModifier = eventModifier{:} ;
                end
                switch eventModifier
                    
                    case 'control'
                        switch key
                            case {'rightarrow','d','D'}
                                direction = 'forward';
                                app.jumpToSyllable(choice,direction);
                            case {'leftarrow','a','A'}
                                direction = 'backward';
                                app.jumpToSyllable(choice,direction);
                            case 'uparrow'
                                direction = 'in';
                                zoomInOut(app,choice,direction)
                                
                            case 'downarrow'
                                direction = 'out';
                                zoomInOut(app,choice,direction)
                            case 's'
                                app.updateSelection;
                            case 'r'
                                roilist = app.findROI(app.currentAx);
                                if ~isempty(roilist)
                                    for r = 1:size(roilist,1)
                                        roilist(r).Label = 'Noise';
                                    end
                                end
                                app.updateSelection;
                        end
                        
                        %                     case 'alt'
                        %                         switch key
                        %                             case 'd'
                        %
                        %                             case 'rightarrow'
                        %
                        %                             case 'leftarrow'
                        %
                        %                         end
                        
                        
                end
            end
        end

        % Menu selected function: ChangejumpRangeMenu
        function ChangejumpRangeMenuSelected(app, event)
            maxVal = 50;
            fig = uifigure('Name','Chnage Jump Range: ',...
                'Position',[400 400 200 50]);
            val = app.jumpRange;
            edt = uieditfield(fig,'numeric',...
                'Position',[0 0 200 50],...
                'Limits', [0 maxVal],...
                'LowerLimitInclusive','off',...
                'UpperLimitInclusive','on',...
                'Value', val,...
                'ValueChangedFcn',@(numfld,event) numberChanged(numfld,app));
            
            function numberChanged(numfld,app)
                app.jumpRange= numfld.Value;
                delete(numfld.Parent)
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            tag = app.currentAx.Tag;
            switch tag
                case {'1','2'}
                    obj = app.mainData;
                case {'3','4'}
                    obj = app.miniData;
            end
            obj.playSegment([],2000,1.2,0.05,[])
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {726, 726, 726};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {726, 726};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {235, '1x', 172};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.LeftPanel);
            app.NavigationMiniMicPanel = uipanel(app.GridLayout6);
            app.NavigationMiniMicPanel.Title = 'Navigation - Mini Mic';
            app.miniNavigation = uigridlayout(app.NavigationMiniMicPanel);
            app.miniBack = uibutton(app.miniNavigation, 'push');
            app.miniBack.ButtonPushedFcn = createCallbackFcn(app, @miniBackButtonPushed, true);
            app.miniBack.Icon = 'backwardarrow.png';
            app.miniBack.Text = '';
            app.miniForward = uibutton(app.miniNavigation, 'push');
            app.miniForward.ButtonPushedFcn = createCallbackFcn(app, @miniForwardButtonPushed, true);
            app.miniForward.Icon = 'forwardarrow.png';
            app.miniForward.Text = '';
            app.miniStartLabel = uilabel(app.miniNavigation);
            app.miniStartLabel.Text = 'Start';
            app.miniStart = uieditfield(app.miniNavigation, 'numeric');
            app.miniStart.ValueChangedFcn = createCallbackFcn(app, @miniStartValueChanged, true);
            app.StepSizeSpinnerLabel = uilabel(app.miniNavigation);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.miniStepSizeSpinner = uispinner(app.miniNavigation);
            app.miniStepSizeSpinner.Limits = [0 Inf];
            app.miniStepSizeSpinner.Value = 1;
            app.miniRefreshPlot = uibutton(app.miniNavigation, 'push');
            app.miniRefreshPlot.ButtonPushedFcn = createCallbackFcn(app, @miniRefreshPlotButtonPushed, true);
            app.miniRefreshPlot.Text = 'Plot';
            app.miniFileName = uilabel(app.miniNavigation);
            app.miniFileName.Text = 'File name2';
            app.miniLoadFileButton = uibutton(app.miniNavigation, 'push');
            app.miniLoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @miniLoadFileButtonPushed, true);
            app.miniLoadFileButton.Text = 'Load File';
            app.miniPrevButton = uibutton(app.miniNavigation, 'push');
            app.miniPrevButton.ButtonPushedFcn = createCallbackFcn(app, @miniPrevButtonPushed, true);
            app.miniPrevButton.Icon = 'tiny-arrow-left-double.png';
            app.miniPrevButton.Text = 'Prev';
            app.miniNextButton = uibutton(app.miniNavigation, 'push');
            app.miniNextButton.ButtonPushedFcn = createCallbackFcn(app, @miniNextButtonPushed, true);
            app.miniNextButton.Icon = 'tiny-arrow-right-double.png';
            app.miniNextButton.Text = 'Next';
            app.miniEndLabel = uilabel(app.miniNavigation);
            app.miniEndLabel.Text = 'End';
            app.miniEnd = uieditfield(app.miniNavigation, 'numeric');
            app.miniEnd.ValueChangedFcn = createCallbackFcn(app, @miniEndValueChanged, true);
            app.miniSetTriggerButton = uibutton(app.miniNavigation, 'push');
            app.miniSetTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @miniSetTriggerButtonPushed, true);
            app.miniSetTriggerButton.Text = 'Set Trigger';
            app.miniTriggerTimeEditField = uieditfield(app.miniNavigation, 'numeric');
            app.miniTriggerTimeEditField.ValueChangedFcn = createCallbackFcn(app, @miniTriggerTimeEditFieldValueChanged, true);
            app.NavigationMainMicPanel = uipanel(app.GridLayout6);
            app.NavigationMainMicPanel.Title = 'Navigation - Main Mic';
            app.mainNavigation = uigridlayout(app.NavigationMainMicPanel);
            app.mainBack = uibutton(app.mainNavigation, 'push');
            app.mainBack.ButtonPushedFcn = createCallbackFcn(app, @mainBackButtonPushed, true);
            app.mainBack.Icon = 'backwardarrow.png';
            app.mainBack.Text = '';
            app.mainForward = uibutton(app.mainNavigation, 'push');
            app.mainForward.ButtonPushedFcn = createCallbackFcn(app, @mainForwardButtonPushed, true);
            app.mainForward.Icon = 'forwardarrow.png';
            app.mainForward.Text = '';
            app.mainStartLabel = uilabel(app.mainNavigation);
            app.mainStartLabel.Text = 'Start';
            app.mainEndLabel = uilabel(app.mainNavigation);
            app.mainEndLabel.Text = 'End';
            app.mainStart = uieditfield(app.mainNavigation, 'numeric');
            app.mainStart.ValueChangedFcn = createCallbackFcn(app, @mainStartValueChanged, true);
            app.mainEnd = uieditfield(app.mainNavigation, 'numeric');
            app.mainEnd.ValueChangedFcn = createCallbackFcn(app, @mainEndValueChanged, true);
            app.StepSizeSpinner_2Label = uilabel(app.mainNavigation);
            app.StepSizeSpinner_2Label.Text = 'Step Size';
            app.mainStepSizeSpinner = uispinner(app.mainNavigation);
            app.mainStepSizeSpinner.Limits = [0 Inf];
            app.mainStepSizeSpinner.Value = 1;
            app.mainRefreshPlot = uibutton(app.mainNavigation, 'push');
            app.mainRefreshPlot.ButtonPushedFcn = createCallbackFcn(app, @mainRefreshPlotButtonPushed, true);
            app.mainRefreshPlot.Text = 'Plot';
            app.mainFileName = uilabel(app.mainNavigation);
            app.mainFileName.Text = 'File name1';
            app.mainLoadFileButton = uibutton(app.mainNavigation, 'push');
            app.mainLoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @mainLoadFileButtonPushed, true);
            app.mainLoadFileButton.Text = 'Load File';
            app.mainNextButton = uibutton(app.mainNavigation, 'push');
            app.mainNextButton.ButtonPushedFcn = createCallbackFcn(app, @mainNextButtonPushed, true);
            app.mainNextButton.Icon = 'tiny-arrow-right-double.png';
            app.mainNextButton.Text = 'Next';
            app.mainPrevButton = uibutton(app.mainNavigation, 'push');
            app.mainPrevButton.ButtonPushedFcn = createCallbackFcn(app, @mainPrevButtonPushed, true);
            app.mainPrevButton.Icon = 'tiny-arrow-left-double.png';
            app.mainPrevButton.Text = 'Prev';
            app.mainTriggerTimeEditField = uieditfield(app.mainNavigation, 'numeric');
            app.mainTriggerTimeEditField.ValueChangedFcn = createCallbackFcn(app, @mainTriggerTimeEditFieldValueChanged, true);
            app.mainSetTriggerButton = uibutton(app.mainNavigation, 'push');
            app.mainSetTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @mainSetTriggerButtonPushed, true);
            app.mainSetTriggerButton.Text = 'Set Trigger';
            app.LinkNavigationStateButton = uibutton(app.mainNavigation, 'state');
            app.LinkNavigationStateButton.ValueChangedFcn = createCallbackFcn(app, @LinkNavigationStateButtonValueChanged, true);
            app.LinkNavigationStateButton.Text = {'Link'; 'Navigation'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.mainSpectAx = uiaxes(app.GridLayout2);
            title(app.mainSpectAx, 'Main Mic')
            xlabel(app.mainSpectAx, '')
            ylabel(app.mainSpectAx, 'kHz')
            app.mainSpectAx.Tag = '1';
            app.mainPSDAx = uiaxes(app.GridLayout2);
            title(app.mainPSDAx, '')
            xlabel(app.mainPSDAx, '')
            ylabel(app.mainPSDAx, 'A.U.')
            app.mainPSDAx.Tag = '2';
            app.miniSpectAx = uiaxes(app.GridLayout2);
            title(app.miniSpectAx, 'Mini Mic')
            xlabel(app.miniSpectAx, '')
            ylabel(app.miniSpectAx, 'Hz')
            app.miniSpectAx.XTickLabel = {'0'; '1'; '0'; '3'; '4'; '5'; '0'; '7'; '8'; '9'; '10'};
            app.miniSpectAx.Tag = '3';
            app.miniPSDAx = uiaxes(app.GridLayout2);
            title(app.miniPSDAx, '')
            xlabel(app.miniPSDAx, '')
            ylabel(app.miniPSDAx, 'A.U.')
            app.miniPSDAx.Tag = '4';
            app.RightPanel = uipanel(app.GridLayout);
            app.rightGrid = uigridlayout(app.RightPanel);
            app.LinkSelectionStateButton = uibutton(app.rightGrid, 'state');
            app.LinkSelectionStateButton.ValueChangedFcn = createCallbackFcn(app, @LinkSelectionStateButtonValueChanged, true);
            app.LinkSelectionStateButton.Text = {'Link'; 'Selection'};
            app.ClearROIsButton = uibutton(app.rightGrid, 'push');
            app.ClearROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearROIsButtonPushed, true);
            app.ClearROIsButton.Text = {'Clear'; 'ROIs'};
            app.UpdateSelectionButton = uibutton(app.rightGrid, 'push');
            app.UpdateSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSelectionButtonPushed, true);
            app.UpdateSelectionButton.Text = {'Update'; 'Selection'};
            app.ReloadROIButton = uibutton(app.rightGrid, 'push');
            app.ReloadROIButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadROIButtonPushed, true);
            app.ReloadROIButton.Text = {'Reload'; 'ROI'};
            app.SelectButton = uibutton(app.rightGrid, 'state');
            app.SelectButton.ValueChangedFcn = createCallbackFcn(app, @SelectButtonValueChanged, true);
            app.SelectButton.Text = 'Select';
            app.ExportSaveButton = uibutton(app.rightGrid, 'push');
            app.ExportSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSaveButtonPushed, true);
            app.ExportSaveButton.Text = 'Export/Save';
            app.ROItypesListBoxLabel = uilabel(app.rightGrid);
            app.ROItypesListBoxLabel.Text = 'ROI types';
            app.ROItypesListBox = uilistbox(app.rightGrid);
            app.ROItypesListBox.ValueChangedFcn = createCallbackFcn(app, @ROItypesListBoxValueChanged, true);
            app.SelectAxesButtonGroup = uibuttongroup(app.rightGrid);
            app.SelectAxesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectAxesButtonGroupSelectionChanged, true);
            app.SelectAxesButtonGroup.Title = 'Select Axes';
            app.selectMainSpect = uiradiobutton(app.SelectAxesButtonGroup);
            app.selectMainSpect.Tag = '1';
            app.selectMainSpect.Text = '1) Main Mic Spect';
            app.selectMainSpect.Value = true;
            app.selectMainPSD = uiradiobutton(app.SelectAxesButtonGroup);
            app.selectMainPSD.Tag = '2';
            app.selectMainPSD.Text = '2) Main Mic PSD';
            app.selectMiniSpect = uiradiobutton(app.SelectAxesButtonGroup);
            app.selectMiniSpect.Tag = '3';
            app.selectMiniSpect.Text = '3) Mini Mic Spect';
            app.selectMiniPSD = uiradiobutton(app.SelectAxesButtonGroup);
            app.selectMiniPSD.Tag = '4';
            app.selectMiniPSD.Text = '4) Mini Mic PSD';
            app.EditButton = uibutton(app.rightGrid, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.AutoDetectionButton = uibutton(app.rightGrid, 'push');
            app.AutoDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @AutoDetectionButtonPushed, true);
            app.AutoDetectionButton.Text = 'Auto Detection';
            app.OverlaySniffingButton = uibutton(app.rightGrid, 'push');
            app.OverlaySniffingButton.ButtonPushedFcn = createCallbackFcn(app, @OverlaySniffingButtonPushed, true);
            app.OverlaySniffingButton.Text = 'Overlay Sniffing';
            app.PlayButton = uibutton(app.rightGrid, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.ROItypesListBox.ContextMenu = app.ContextMenu;
            app.EditMenu = uimenu(app.ContextMenu);
            app.EditMenu.MenuSelectedFcn = createCallbackFcn(app, @EditMenuSelected, true);
            app.EditMenu.Text = 'Edit';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu2
            app.miniPrevButton.ContextMenu = app.ContextMenu2;
            app.miniNextButton.ContextMenu = app.ContextMenu2;
            app.mainNextButton.ContextMenu = app.ContextMenu2;
            app.mainPrevButton.ContextMenu = app.ContextMenu2;
            app.ChangejumpRangeMenu = uimenu(app.ContextMenu2);
            app.ChangejumpRangeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangejumpRangeMenuSelected, true);
            app.ChangejumpRangeMenu.Text = 'Change jump Range';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audioBrowser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end