classdef editRoisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        UITable            matlab.ui.control.Table
        ResetButton        matlab.ui.control.Button
        SelectColorButton  matlab.ui.control.Button
        AddRowsButton      matlab.ui.control.Button
        RemoveButton       matlab.ui.control.Button
        UpdateButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        currentTable
        obj
        selectedCell = [1,1];
        originalTable
    end
    
    methods (Access = private)
        
        function app = refreshTable(app)
            app.UITable.Data = app.currentTable;
            sz = size(app.UITable.Data,1);
            s = cellfun(@uistyle,repmat("FontColor",[sz,1]),num2cell(app.UITable.Data.Color,2));
            for r = 1:sz
                addStyle(app.UITable,s(r),'row',r);
            end
            figure(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, objin)
            app.obj = objin;
            tab = objin.addedTypeList;
            app.originalTable = tab;
            app.currentTable = tab;
            app.refreshTable;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            if indices(2)~=1 %If the name value is changed, make sure it doesn't exist
                app.currentTable = app.UITable.Data;
            else
                newData = event.NewData;
                tab = app.currentTable;
                if ismember(newData,tab.Name)
                    app.UITable.Data(indices(1),indices(2)) = tab(indices(1),indices(2));
                else
                    app.currentTable = app.UITable.Data;
                end
            end
        end

        % Button pushed function: AddRowsButton
        function AddRowsButtonPushed(app, event)
            t = app.UITable.Data;
            
            %Give unique name
            roylist = t.Name;
            counter = 1;
            newROIName = 'untitled';
            if ismember(newROIName,roylist)
                while ismember(newROIName,roylist)
                    newROIName = sprintf('untitled%d',counter);
                    counter = counter+1;
                end
            end
            app.currentTable = [t;{newROIName,[0 0 0],""}];
            app.UITable.Data = [t;{newROIName,[0 0 0],""}];
            app.refreshTable;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            inds = app.selectedCell;
            t = app.currentTable;
            if ~isempty(t)
                t(inds(1),:) = [];
            end
            app.UITable.Data = t;
            app.currentTable = t;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.selectedCell = indices;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            t = app.originalTable;
            app.currentTable = t;
            app.UITable.Data = t;
            app.refreshTable;
        end

        % Button pushed function: SelectColorButton
        function SelectColorButtonPushed(app, event)
            c = uisetcolor;
            inds = app.selectedCell;
            if isvector(c)
                t = app.currentTable;
                t{inds(1),2} = c;
                app.currentTable = t;
                app.UITable.Data = t;
                app.refreshTable;
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.obj.addedTypeList = app.UITable.Data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Name'; 'Color'; 'Comments'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SelectColorButton = uibutton(app.GridLayout, 'push');
            app.SelectColorButton.ButtonPushedFcn = createCallbackFcn(app, @SelectColorButtonPushed, true);
            app.SelectColorButton.Text = 'Select Color';
            app.AddRowsButton = uibutton(app.GridLayout, 'push');
            app.AddRowsButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowsButtonPushed, true);
            app.AddRowsButton.Text = 'Add Rows';
            app.RemoveButton = uibutton(app.GridLayout, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.UpdateButton = uibutton(app.GridLayout, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = editRoisApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end