classdef vocalizationsLabelingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LoadMiniMicButton     matlab.ui.control.Button
        AudioBrowserButton    matlab.ui.control.Button
        AudioLabelerButton    matlab.ui.control.Button
        PathMain              matlab.ui.control.Label
        LoadMainMicButton     matlab.ui.control.Button
        PathMini              matlab.ui.control.Label
        RatNumerLabel         matlab.ui.control.Label
        ParadigmDropDown      matlab.ui.control.DropDown
        ParadigmLabel         matlab.ui.control.Label
        DayLabel              matlab.ui.control.Label
        DetailsLabel          matlab.ui.control.Label
        DayNumSpinner         matlab.ui.control.Spinner
        RatNumSpinner         matlab.ui.control.Spinner
        CreateImagesButton    matlab.ui.control.Button
        CreateImagesButton_2  matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainMicAudioPath = 0; % Path to the main mic audio file
        mainMicAudioVec = [];  % Main mic audio vector
        miniMicAudioPath = 0; % Path to the mini mic audio file
        miniMicAudioVec = [];  % Mini mic audio vector
        ratDetails = struct('fileName','','filePath','','ratNum',[],'paradigm','','dayNum',[]); %Recording details
    end
    
    methods (Access = private)
        
        function setRecordingDetails(app)
            ratdetails = app.ratDetails;
            if ratdetails.ratNum>=app.RatNumSpinner.Limits(1)&&ratdetails.ratNum<=app.RatNumSpinner.Limits(2)
                app.RatNumSpinner.Value = ratdetails.ratNum;
            end
            if ratdetails.dayNum>=app.DayNumSpinner.Limits(1)&&ratdetails.dayNum<=app.DayNumSpinner.Limits(2)
                app.DayNumSpinner.Value = ratdetails.dayNum;
            end
            if ~isempty(ratdetails.paradigm)
                app.ParadigmDropDown.Value = ratdetails.paradigm;
            end
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadMainMicButton
        function LoadMainMicButtonPushed(app, event)
            %Load audio file
            [file,path] = uigetfile('*.wav');
            if file==0 %If the load was cancelled
                
                return
            else
                app.mainMicAudioPath = fullfile(path,file); %Set main mic audio file path
                [~,app.PathMain.Text] = fileparts(file);
                app.ratDetails = getRecodringDetails(file,path,app.PathMain.Text);
                setRecordingDetails(app);
                app.mainMicAudioVec = audioread(app.mainMicAudioPath);
            end
        end

        % Button pushed function: LoadMiniMicButton
        function LoadMiniMicButtonPushed(app, event)
            %Load audio file
            [file,path] = uigetfile('*.wav');
            if file==0 %If the load was cancelled
                return
            else
                app.miniMicAudioPath = fullfile(path,file); %Set main mic audio file path
                [~,app.PathMini.Text] = fileparts(file);
                app.ratDetails = getRecodringDetails(file,path,app.PathMain.Text);
                setRecordingDetails(app);
                app.miniMicAudioVec = audioread(app.miniMicAudioPath);
            end
        end

        % Value changed function: RatNumSpinner
        function RatNumSpinnerValueChanged(app, event)
            value = app.RatNumSpinner.Value;
            app.ratDetails.ratNum = value;
        end

        % Value changed function: ParadigmDropDown
        function ParadigmDropDownValueChanged(app, event)
            value = app.ParadigmDropDown.Value;
            app.ratDetails.paradigm = value;
        end

        % Value changed function: DayNumSpinner
        function DayNumSpinnerValueChanged(app, event)
            value = app.DayNumSpinner.Value;
            app.ratDetails.dayNum = value;
        end

        % Button pushed function: AudioBrowserButton
        function AudioBrowserButtonPushed(app, event)
            if ~(isempty(app.mainMicAudioVec)&&isempty(app.miniMicAudioVec))
                audioBrowser(audioClip(app.mainMicAudioVec,app.ratDetails),audioClip(app.miniMicAudioVec,app.ratDetails));
            else
               msgbox('No audio files were loaded');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LoadMiniMicButton = uibutton(app.GridLayout, 'push');
            app.LoadMiniMicButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMiniMicButtonPushed, true);
            app.LoadMiniMicButton.Text = 'Load Mini Mic';
            app.AudioBrowserButton = uibutton(app.GridLayout, 'push');
            app.AudioBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @AudioBrowserButtonPushed, true);
            app.AudioBrowserButton.Text = 'Audio Browser';
            app.AudioLabelerButton = uibutton(app.GridLayout, 'push');
            app.AudioLabelerButton.Text = 'Audio Labeler';
            app.PathMain = uilabel(app.GridLayout);
            app.PathMain.Text = 'Path';
            app.LoadMainMicButton = uibutton(app.GridLayout, 'push');
            app.LoadMainMicButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMainMicButtonPushed, true);
            app.LoadMainMicButton.Text = 'Load Main Mic';
            app.PathMini = uilabel(app.GridLayout);
            app.PathMini.Text = 'Path';
            app.RatNumerLabel = uilabel(app.GridLayout);
            app.RatNumerLabel.Text = 'Rat Numer';
            app.ParadigmDropDown = uidropdown(app.GridLayout);
            app.ParadigmDropDown.Items = {'Chamber', '''''', 'Free'};
            app.ParadigmDropDown.ValueChangedFcn = createCallbackFcn(app, @ParadigmDropDownValueChanged, true);
            app.ParadigmDropDown.Value = 'Chamber';
            app.ParadigmLabel = uilabel(app.GridLayout);
            app.ParadigmLabel.Text = 'Paradigm';
            app.DayLabel = uilabel(app.GridLayout);
            app.DayLabel.Text = 'Day';
            app.DetailsLabel = uilabel(app.GridLayout);
            app.DetailsLabel.Text = 'Details';
            app.DayNumSpinner = uispinner(app.GridLayout);
            app.DayNumSpinner.Limits = [0 Inf];
            app.DayNumSpinner.ValueDisplayFormat = '%.0f';
            app.DayNumSpinner.ValueChangedFcn = createCallbackFcn(app, @DayNumSpinnerValueChanged, true);
            app.RatNumSpinner = uispinner(app.GridLayout);
            app.RatNumSpinner.Limits = [0 Inf];
            app.RatNumSpinner.ValueDisplayFormat = '%.0f';
            app.RatNumSpinner.ValueChangedFcn = createCallbackFcn(app, @RatNumSpinnerValueChanged, true);
            app.CreateImagesButton = uibutton(app.GridLayout, 'push');
            app.CreateImagesButton.Text = 'Create Images';
            app.CreateImagesButton_2 = uibutton(app.GridLayout, 'push');
            app.CreateImagesButton_2.Text = 'Create Images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vocalizationsLabelingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end